classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LDR_Dashboard              matlab.ui.Figure
        Page                       matlab.ui.container.GridLayout
        Header                     matlab.ui.container.GridLayout
        Title                      matlab.ui.control.Label
        Contents                   matlab.ui.container.GridLayout
        Overview                   matlab.ui.container.GridLayout
        DateFilter                 matlab.ui.container.GridLayout
        FromDate                   matlab.ui.container.GridLayout
        FromDatePickerLabel        matlab.ui.control.Label
        FromDatePicker             matlab.ui.control.DatePicker
        ToDate                     matlab.ui.container.GridLayout
        ToDatePickerLabel          matlab.ui.control.Label
        ToDatePicker               matlab.ui.control.DatePicker
        IndustryOverview           matlab.ui.container.GridLayout
        TotalDeposits              matlab.ui.container.GridLayout
        TotalDepositsLabel         matlab.ui.control.Label
        TotalDepositsValue         matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        TotalLoansLabel            matlab.ui.control.Label
        TotalLoansValue            matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        LoantoDepositRatioLabel    matlab.ui.control.Label
        LoandtoDepositRatioValue   matlab.ui.control.Label
        DataAnalysis               matlab.ui.container.GridLayout
        DepositsPerBank            matlab.ui.control.UIAxes
        LoansPerBank               matlab.ui.control.UIAxes
        LoantoDepositRatioPerBank  matlab.ui.control.UIAxes
        LoantoDepositRatioTrend    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        start_date % Description
        end_date % Description  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FromDatePicker
        function FromDatePickerValueChanged(app, event)
            app.start_date =app.FromDatePicker.Value;
            msg = ['Start Date Changed To ' class(app.start_date)];
            % Confirm new date
            selection = uiconfirm(f,msg,'Confirm Date');
            app.start_date = datetime(app.start_date);
        end

        % Value changed function: ToDatePicker
        function ToDatePickerValueChanged(app, event)
            app.end_date=app.ToDatePicker.Value;
            msg = ['End Date Changed To ' class(app.end_date)];
            % Confirm new date
            selection = uiconfirm(f,msg,'Confirm Date');
            app.end_date= datetime(app.end_date);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LDR_Dashboard = uifigure('Visible', 'off');
            app.LDR_Dashboard.Name = 'UI Figure';
            app.Page = uigridlayout(app.LDR_Dashboard);
            app.Header = uigridlayout(app.Page);
            app.Title = uilabel(app.Header);
            app.Title.Tooltip = {'loan-to-deposit ratio (LDR) is used to assess a bank''s liquidity by comparing a bank''s total loans to its total deposits for the same period.'};
            app.Title.Text = 'Total Loans & Deposits';
            app.Contents = uigridlayout(app.Page);
            app.Overview = uigridlayout(app.Contents);
            app.DateFilter = uigridlayout(app.Overview);
            app.FromDate = uigridlayout(app.DateFilter);
            app.FromDatePickerLabel = uilabel(app.FromDate);
            app.FromDatePickerLabel.Text = 'From:';
            app.FromDatePicker = uidatepicker(app.FromDate);
            app.FromDatePicker.ValueChangedFcn = createCallbackFcn(app, @FromDatePickerValueChanged, true);
            app.ToDate = uigridlayout(app.DateFilter);
            app.ToDatePickerLabel = uilabel(app.ToDate);
            app.ToDatePickerLabel.Text = 'To:';
            app.ToDatePicker = uidatepicker(app.ToDate);
            app.ToDatePicker.ValueChangedFcn = createCallbackFcn(app, @ToDatePickerValueChanged, true);
            app.IndustryOverview = uigridlayout(app.Overview);
            app.TotalDeposits = uigridlayout(app.IndustryOverview);
            app.TotalDepositsLabel = uilabel(app.TotalDeposits);
            app.TotalDepositsLabel.Text = 'Total Deposits';
            app.TotalDepositsValue = uilabel(app.TotalDeposits);
            app.TotalDepositsValue.Text = 'R 542';
            app.GridLayout2 = uigridlayout(app.IndustryOverview);
            app.TotalLoansLabel = uilabel(app.GridLayout2);
            app.TotalLoansLabel.Text = 'Total Loans';
            app.TotalLoansValue = uilabel(app.GridLayout2);
            app.TotalLoansValue.Text = 'R 178';
            app.GridLayout3 = uigridlayout(app.IndustryOverview);
            app.LoantoDepositRatioLabel = uilabel(app.GridLayout3);
            app.LoantoDepositRatioLabel.Text = {'Loan-to-Deposit Ratio'; ''};
            app.LoandtoDepositRatioValue = uilabel(app.GridLayout3);
            app.LoandtoDepositRatioValue.Text = '56%';
            app.DataAnalysis = uigridlayout(app.Contents);
            app.DepositsPerBank = uiaxes(app.DataAnalysis);
            title(app.DepositsPerBank, 'Market Share Deposits per Bank')
            xlabel(app.DepositsPerBank, 'Market Share')
            ylabel(app.DepositsPerBank, 'Bank')
            app.LoansPerBank = uiaxes(app.DataAnalysis);
            title(app.LoansPerBank, 'Market Share Loans per Bank')
            xlabel(app.LoansPerBank, 'Loan Share')
            ylabel(app.LoansPerBank, 'Bank')
            app.LoantoDepositRatioPerBank = uiaxes(app.DataAnalysis);
            title(app.LoantoDepositRatioPerBank, 'Loan to Deposite Ratio')
            xlabel(app.LoantoDepositRatioPerBank, 'Loan to Deposite Ratio')
            ylabel(app.LoantoDepositRatioPerBank, 'Bank')
            app.LoantoDepositRatioTrend = uiaxes(app.Contents);
            title(app.LoantoDepositRatioTrend, 'Loan to deposit ratio trends per bank')
            xlabel(app.LoantoDepositRatioTrend, 'Year')
            ylabel(app.LoantoDepositRatioTrend, 'Loan to Deposit Ratio')
            % Show the figure after all components are created
            app.LDR_Dashboard.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LDR_Dashboard)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LDR_Dashboard)
        end
    end
end